classdef PKE_NoFeedback_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PKE_NFUIFigure                 matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ParametersTab                  matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        ModelOptionsPanel              matlab.ui.container.Panel
        InitialPowerWEditFieldLabel    matlab.ui.control.Label
        InitialPowerWEditField         matlab.ui.control.NumericEditField
        GenerationTimeEditFieldLabel   matlab.ui.control.Label
        GenerationTimeEditField        matlab.ui.control.NumericEditField
        BetaEffectiveEditFieldLabel    matlab.ui.control.Label
        BetaEffectiveEditField         matlab.ui.control.NumericEditField
        InputReactivityEditFieldLabel  matlab.ui.control.Label
        InputReactivityEditField       matlab.ui.control.NumericEditField
        RunSettingsPanel               matlab.ui.container.Panel
        StopTimesEditFieldLabel        matlab.ui.control.Label
        StopTimesEditField             matlab.ui.control.NumericEditField
        SimulateButton                 matlab.ui.control.Button
        betaEditFieldLabel             matlab.ui.control.Label
        betaEditField                  matlab.ui.control.EditField
        lambdaEditFieldLabel           matlab.ui.control.Label
        lambdaEditField                matlab.ui.control.EditField
        PowerTab                       matlab.ui.container.Tab
        UIAxesPower                    matlab.ui.control.UIAxes
        FinalPowerEditFieldLabel       matlab.ui.control.Label
        FinalPowerEditField            matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)


        function toggleUIC(app, state, label)
            % toggle the state of the uicontrols in the app
            uics = findall(app.PKE_NFUIFigure);
            for idx = 1:length(uics)
               uic = uics(idx);
               if isprop(uic,'Enable')
                   uic.Enable = state;
               end
            end
            % update the simulate button text
            app.SimulateButton.Text = label;
            drawnow; % flush the ui events queue
        end % toggleUIC
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load simulink to pay the one time cost of loading libraries
            % % during app startup.
            start_simulink;
            
            betaTable = {0.00026 0.00146 0.00129 0.00279 0.00088 0.00018};
            lambdaTable = {0.0127 0.0317 0.1160 0.3111 1.4003 3.8708};
            t = table(betaTable',lambdaTable');
            app.UITable.Data = t;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
                %app.toggleUIC('off', 'Simulating ...');
                mdl = 'PKE_NoFeedback';
                simInp = Simulink.SimulationInput(mdl);
                
                betaChr = app.betaEditField.Value;
                betaStrip = split(betaChr,{' ',','});
                betaMat = cell2mat(betaStrip);
                betat = str2num(betaMat);
                
                lamChr = app.lambdaEditField.Value;
                lamStrip = split(lamChr,{' ',','});
                lamMat = cell2mat(lamStrip);
                lamt = str2num(lamMat);
                
                lam = lamt';
                beta = betat';
                
                %Update Table
                t = table(beta',lam');
                app.UITable.Data = t;
                
                stopTimeStr = num2str(app.StopTimesEditField.Value);
                simInp = simInp.setModelParameter('StopTime', stopTimeStr);
                
                %simInp.ExternalInput = app.externalInput();
                
                simInp = simInp.setVariable('rho',app.InputReactivityEditField.Value);
                Po = app.InitialPowerWEditField.Value;
                Be = app.BetaEffectiveEditField.Value;
                Gt = app.GenerationTimeEditField.Value;
                %beta = [0.00026 0.00146 0.00129 0.00279 0.00088 0.00018];
                %0.01270,0.03170,0.11600,0.31110,1.40030,3.87080
                %lam  = [0.01270 0.03170 0.11600 0.31110 1.40030 3.87080];
                Ibar = Be/sum(beta);
                B = Ibar*beta;
                Co = B*Po./(lam*Gt);
                simInp = simInp.setVariable('Be',app.BetaEffectiveEditField.Value);
                simInp = simInp.setVariable('Gt',app.GenerationTimeEditField.Value);
                simInp = simInp.setVariable('PoMW',app.InitialPowerWEditField.Value);
                simInp = simInp.setVariable('beta', beta);%[0.00026 0.00146 0.00129 0.00279 0.00088 0.00018]);
                simInp = simInp.setVariable('lam', lam);%[0.0127 0.0317 0.1160 0.3111 1.4003 3.8708]);
                %simInp = simInp.setVariable('Ibar', Be/sum(beta));
                simInp = simInp.setVariable('B', Ibar*beta);
                simInp = simInp.setVariable('td', sum(B./lam)/Be);
                simInp = simInp.setVariable('Co', Co);
                
                
                simInp = simulink.compiler.configureForDeployment(simInp); 
                simOut = sim(simInp);
                % Get time values
                t = simOut.tout;
                % Get power data
                yp = simOut.yout{1}.Values.Data;
                % Plot power
                plot(app.UIAxesPower, t, yp)
                app.FinalPowerEditField.Value = simOut.yout{1}.Values.Data(end);
            catch ME
                errordlg(ME.message);
            end
            %app.toggleUIC('on', 'Simulate');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PKE_NFUIFigure = uifigure('Visible', 'off');
            app.PKE_NFUIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.PKE_NFUIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.UITable = uitable(app.ParametersTab);
            app.UITable.ColumnName = {'beta'; 'lambda'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false];
            app.ModelOptionsPanel = uipanel(app.ParametersTab);
            app.ModelOptionsPanel.Title = 'Model Options';
            app.InitialPowerWEditFieldLabel = uilabel(app.ModelOptionsPanel);
            app.InitialPowerWEditFieldLabel.Text = 'Initial Power (W)';
            app.InitialPowerWEditField = uieditfield(app.ModelOptionsPanel, 'numeric');
            app.InitialPowerWEditField.Limits = [0 Inf];
            app.InitialPowerWEditField.Value = 5000;
            app.GenerationTimeEditFieldLabel = uilabel(app.ModelOptionsPanel);
            app.GenerationTimeEditFieldLabel.Text = 'Generation Time';
            app.GenerationTimeEditField = uieditfield(app.ModelOptionsPanel, 'numeric');
            app.GenerationTimeEditField.Limits = [0 Inf];
            app.GenerationTimeEditField.ValueDisplayFormat = '%.2e';
            app.GenerationTimeEditField.Value = 0.00065;
            app.BetaEffectiveEditFieldLabel = uilabel(app.ModelOptionsPanel);
            app.BetaEffectiveEditFieldLabel.Text = 'Beta Effective';
            app.BetaEffectiveEditField = uieditfield(app.ModelOptionsPanel, 'numeric');
            app.BetaEffectiveEditField.Limits = [0 1];
            app.BetaEffectiveEditField.Value = 0.0078;
            app.InputReactivityEditFieldLabel = uilabel(app.ModelOptionsPanel);
            app.InputReactivityEditFieldLabel.Text = 'Input Reactivity ($)';
            app.InputReactivityEditField = uieditfield(app.ModelOptionsPanel, 'numeric');
            app.InputReactivityEditField.Value = 0.03;
            app.RunSettingsPanel = uipanel(app.ParametersTab);
            app.RunSettingsPanel.Title = 'Run Settings';
            app.StopTimesEditFieldLabel = uilabel(app.RunSettingsPanel);
            app.StopTimesEditFieldLabel.Text = 'Stop Time (s)';
            app.StopTimesEditField = uieditfield(app.RunSettingsPanel, 'numeric');
            app.StopTimesEditField.Limits = [0 Inf];
            app.StopTimesEditField.Value = 300;
            app.SimulateButton = uibutton(app.RunSettingsPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.betaEditFieldLabel = uilabel(app.ParametersTab);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.ParametersTab, 'text');
            app.betaEditField.Value = '0.00026, 0.00146, 0.00129, 0.00279, 0.00088, 0.00018';
            app.lambdaEditFieldLabel = uilabel(app.ParametersTab);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.ParametersTab, 'text');
            app.lambdaEditField.Value = '0.01270, 0.03170, 0.11600, 0.31110, 1.40030, 3.87080';
            app.PowerTab = uitab(app.TabGroup);
            app.PowerTab.Title = 'Power';
            app.UIAxesPower = uiaxes(app.PowerTab);
            title(app.UIAxesPower, 'Reactor Power vs Time')
            xlabel(app.UIAxesPower, 'Time (seconds)')
            ylabel(app.UIAxesPower, 'Power (kW)')
            app.FinalPowerEditFieldLabel = uilabel(app.PowerTab);
            app.FinalPowerEditFieldLabel.Text = 'Final Power';
            app.FinalPowerEditField = uieditfield(app.PowerTab, 'numeric');
            % Show the figure after all components are created
            app.PKE_NFUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PKE_NoFeedback_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PKE_NFUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PKE_NFUIFigure)
        end
    end
end